classdef dice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        gamerule                     matlab.ui.control.TextArea
        seetherulesfirstButton       matlab.ui.control.Button
        Image10                      matlab.ui.control.Image
        Image9                       matlab.ui.control.Image
        Image8                       matlab.ui.control.Image
        Image7                       matlab.ui.control.Image
        Image6                       matlab.ui.control.Image
        Image5                       matlab.ui.control.Image
        Image4                       matlab.ui.control.Image
        Image3                       matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        seethescoreButton            matlab.ui.control.Button
        UITable2                     matlab.ui.control.Table
        instructionEditField         matlab.ui.control.EditField
        instructionEditFieldLabel    matlab.ui.control.Label
        p2scoreEditField             matlab.ui.control.NumericEditField
        p2scoreEditFieldLabel        matlab.ui.control.Label
        p1scoreEditField             matlab.ui.control.NumericEditField
        p1scoreEditFieldLabel        matlab.ui.control.Label
        startgameButton              matlab.ui.control.Button
        numberofgamesEditField       matlab.ui.control.NumericEditField
        numberofgamesEditFieldLabel  matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        p2finishrollingButton        matlab.ui.control.Button
        p1finishrollingButton        matlab.ui.control.Button
        p2dice5EditField             matlab.ui.control.NumericEditField
        p2dice5EditFieldLabel        matlab.ui.control.Label
        p2dice4EditField             matlab.ui.control.NumericEditField
        p2dice4EditFieldLabel        matlab.ui.control.Label
        p2dice3EditField             matlab.ui.control.NumericEditField
        p2dice3EditFieldLabel        matlab.ui.control.Label
        p2dice2EditField             matlab.ui.control.NumericEditField
        p2dice2EditFieldLabel        matlab.ui.control.Label
        p2dice1EditField             matlab.ui.control.NumericEditField
        p2dice1EditFieldLabel        matlab.ui.control.Label
        p1dice5EditField             matlab.ui.control.NumericEditField
        p1dice5EditFieldLabel        matlab.ui.control.Label
        p1dice4EditField             matlab.ui.control.NumericEditField
        p1dice4EditFieldLabel        matlab.ui.control.Label
        p1dice3EditField             matlab.ui.control.NumericEditField
        p1dice3EditFieldLabel        matlab.ui.control.Label
        p1dice2EditField             matlab.ui.control.NumericEditField
        p1dice2EditFieldLabel        matlab.ui.control.Label
        p1dice1EditField             matlab.ui.control.NumericEditField
        p1dice1EditFieldLabel        matlab.ui.control.Label
        p1minus1                     matlab.ui.control.Button
        p1plus1                      matlab.ui.control.Button
        p2minus1                     matlab.ui.control.Button
        p2plus1                      matlab.ui.control.Button
        player2rollthediceButton     matlab.ui.control.Button
        player1rollthediceButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        p1s
        p2s
        p1diceinarray
        p1compare
        p2diceinarray
        p2compare
        times
        p1sum
        p2sum
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.p1dice1EditField.Value=0;
            set(app.p1plus1,'enable','off');
            set(app.p1minus1,'enable','off');
            set(app.p2plus1,'enable','off');
            set(app.p2minus1,'enable','off');
            set(app.p1finishrollingButton,'enable','off');
            set(app.p2finishrollingButton,'enable','off');
            app.p1s=0;
            app.p2s=0;
            set(app.seethescoreButton,'enable','off')
            app.UITable.Data={8,7,6,5,4,3,2,1};
            app.numberofgamesEditField.Value=0;
            set(app.player1rollthediceButton,'enable','off');
            set(app.player2rollthediceButton,'enable','off');
            set(app.startgameButton,'enable','off');
            app.p1scoreEditField.Value=0;
            app.p2scoreEditField.Value=0;
            app.times.Value=1;
            app.p1sum.Value=0;
            app.p2sum.Value=0;
            
            imread('1.png');
            imread('2.png');
            imread('3.png');
            imread('4.png');
            imread('5.png');
            imread('6.png');
            set(app.gamerule,'visible','off');
            
            set(app.Image,'visible','off');
            set(app.Image2,'visible','off');
            set(app.Image3,'visible','off');
            set(app.Image4,'visible','off');
            set(app.Image5,'visible','off');
            set(app.Image6,'visible','off');
            set(app.Image7,'visible','off');
            set(app.Image8,'visible','off');
            set(app.Image9,'visible','off');
            set(app.Image10,'visible','off');
        end

        % Button pushed function: player1rollthediceButton
        function player1rollthediceButtonPushed(app, event)
            
            app.p1dice1EditField.Value=randi([1,6],[1,1,1]);
            app.p1dice2EditField.Value=randi([1,6],[1,1,1]);
            app.p1dice3EditField.Value=randi([1,6],[1,1,1]);
            app.p1dice4EditField.Value=randi([1,6],[1,1,1]);
            app.p1dice5EditField.Value=randi([1,6],[1,1,1]);
            set(app.p1plus1,'enable','on');
            set(app.p1minus1,'enable','on');
            set(app.p1finishrollingButton,'enable','on');
            set(app.Image,'visible','on');
            set(app.Image2,'visible','on');
            set(app.Image3,'visible','on');
            set(app.Image4,'visible','on');
            set(app.Image5,'visible','on');
            if app.p1dice1EditField.Value==1
                app.Image.ImageSource='1.png';
            elseif app.p1dice1EditField.Value==2
                app.Image.ImageSource='2.png';
            elseif app.p1dice1EditField.Value==3
                app.Image.ImageSource='3.png';
            elseif app.p1dice1EditField.Value==4
                app.Image.ImageSource='4.png';
            elseif app.p1dice1EditField.Value==5
                app.Image.ImageSource='5.png';
            elseif app.p1dice1EditField.Value==6
                app.Image.ImageSource='6.png';
            end

            if app.p1dice2EditField.Value==1
                app.Image2.ImageSource='1.png';
            elseif app.p1dice2EditField.Value==2
                app.Image2.ImageSource='2.png';
            elseif app.p1dice2EditField.Value==3
                app.Image2.ImageSource='3.png';
            elseif app.p1dice2EditField.Value==4
                app.Image2.ImageSource='4.png';
            elseif app.p1dice2EditField.Value==5
                app.Image2.ImageSource='5.png';
            elseif app.p1dice2EditField.Value==6
                app.Image2.ImageSource='6.png';
            end

            if app.p1dice3EditField.Value==1
                app.Image3.ImageSource='1.png';
            elseif app.p1dice3EditField.Value==2
                app.Image3.ImageSource='2.png';
            elseif app.p1dice3EditField.Value==3
                app.Image3.ImageSource='3.png';
            elseif app.p1dice3EditField.Value==4
                app.Image3.ImageSource='4.png';
            elseif app.p1dice3EditField.Value==5
                app.Image3.ImageSource='5.png';
            elseif app.p1dice3EditField.Value==6
                app.Image3.ImageSource='6.png';
            end
            if app.p1dice4EditField.Value==1
                app.Image4.ImageSource='1.png';
            elseif app.p1dice4EditField.Value==2
                app.Image4.ImageSource='2.png';
            elseif app.p1dice4EditField.Value==3
                app.Image4.ImageSource='3.png';
            elseif app.p1dice4EditField.Value==4
                app.Image4.ImageSource='4.png';
            elseif app.p1dice4EditField.Value==5
                app.Image4.ImageSource='5.png';
            elseif app.p1dice4EditField.Value==6
                app.Image4.ImageSource='6.png';
            end
            if app.p1dice5EditField.Value==1
                app.Image5.ImageSource='1.png';
            elseif app.p1dice5EditField.Value==2
                app.Image5.ImageSource='2.png';
            elseif app.p1dice5EditField.Value==3
                app.Image5.ImageSource='3.png';
            elseif app.p1dice5EditField.Value==4
                app.Image5.ImageSource='4.png';
            elseif app.p1dice5EditField.Value==5
                app.Image5.ImageSource='5.png';
            elseif app.p1dice5EditField.Value==6
                app.Image5.ImageSource='6.png';
            end
            
        end

        % Button pushed function: p1plus1
        function p1plus1ButtonPushed(app, event)
            if app.p1dice5EditField.Value<6
                app.p1dice5EditField.Value=app.p1dice5EditField.Value+1;
            end
            set(app.p1plus1,'enable','off');
            set(app.p1minus1,'enable','off');
            if app.p1dice5EditField.Value==1
                app.Image5.ImageSource='1.png';
            elseif app.p1dice5EditField.Value==2
                app.Image5.ImageSource='2.png';
            elseif app.p1dice5EditField.Value==3
                app.Image5.ImageSource='3.png';
            elseif app.p1dice5EditField.Value==4
                app.Image5.ImageSource='4.png';
            elseif app.p1dice5EditField.Value==5
                app.Image5.ImageSource='5.png';
            elseif app.p1dice5EditField.Value==6
                app.Image5.ImageSource='6.png';
            end
        end

        % Button pushed function: p1minus1
        function p1minus1ButtonPushed(app, event)
            if app.p1dice5EditField.Value>1
                app.p1dice5EditField.Value=app.p1dice5EditField.Value-1;
            end
            set(app.p1minus1,'enable','off');
            set(app.p1plus1,'enable','off');
            if app.p1dice5EditField.Value==1
                app.Image5.ImageSource='1.png';
            elseif app.p1dice5EditField.Value==2
                app.Image5.ImageSource='2.png';
            elseif app.p1dice5EditField.Value==3
                app.Image5.ImageSource='3.png';
            elseif app.p1dice5EditField.Value==4
                app.Image5.ImageSource='4.png';
            elseif app.p1dice5EditField.Value==5
                app.Image5.ImageSource='5.png';
            elseif app.p1dice5EditField.Value==6
                app.Image5.ImageSource='6.png';
            end
        end

        % Button pushed function: player2rollthediceButton
        function player2rollthediceButtonPushed(app, event)
            app.p2dice1EditField.Value=randi([1,6],[1,1,1]);
            app.p2dice2EditField.Value=randi([1,6],[1,1,1]);
            app.p2dice3EditField.Value=randi([1,6],[1,1,1]);
            app.p2dice4EditField.Value=randi([1,6],[1,1,1]);
            app.p2dice5EditField.Value=randi([1,6],[1,1,1]);
            set(app.p2plus1,'enable','on');
            set(app.p2minus1,'enable','on');
            set(app.p2finishrollingButton,'enable','on');
            set(app.Image6,'visible','on');
            set(app.Image7,'visible','on');
            set(app.Image8,'visible','on');
            set(app.Image9,'visible','on');
            set(app.Image10,'visible','on');
            if app.p2dice1EditField.Value==1
                app.Image6.ImageSource='1.png';
            elseif app.p2dice1EditField.Value==2
                app.Image6.ImageSource='2.png';
            elseif app.p2dice1EditField.Value==3
                app.Image6.ImageSource='3.png';
            elseif app.p2dice1EditField.Value==4
                app.Image6.ImageSource='4.png';
            elseif app.p2dice1EditField.Value==5
                app.Image6.ImageSource='5.png';
            elseif app.p2dice1EditField.Value==6
                app.Image6.ImageSource='6.png';
            end

            if app.p2dice2EditField.Value==1
                app.Image7.ImageSource='1.png';
            elseif app.p2dice2EditField.Value==2
                app.Image7.ImageSource='2.png';
            elseif app.p2dice2EditField.Value==3
                app.Image7.ImageSource='3.png';
            elseif app.p2dice2EditField.Value==4
                app.Image7.ImageSource='4.png';
            elseif app.p2dice2EditField.Value==5
                app.Image7.ImageSource='5.png';
            elseif app.p2dice2EditField.Value==6
                app.Image7.ImageSource='6.png';
            end

            if app.p2dice3EditField.Value==1
                app.Image8.ImageSource='1.png';
            elseif app.p2dice3EditField.Value==2
                app.Image8.ImageSource='2.png';
            elseif app.p2dice3EditField.Value==3
                app.Image8.ImageSource='3.png';
            elseif app.p2dice3EditField.Value==4
                app.Image8.ImageSource='4.png';
            elseif app.p2dice3EditField.Value==5
                app.Image8.ImageSource='5.png';
            elseif app.p2dice3EditField.Value==6
                app.Image8.ImageSource='6.png';
            end
            if app.p2dice4EditField.Value==1
                app.Image9.ImageSource='1.png';
            elseif app.p2dice4EditField.Value==2
                app.Image9.ImageSource='2.png';
            elseif app.p2dice4EditField.Value==3
                app.Image9.ImageSource='3.png';
            elseif app.p2dice4EditField.Value==4
                app.Image9.ImageSource='4.png';
            elseif app.p2dice4EditField.Value==5
                app.Image9.ImageSource='5.png';
            elseif app.p2dice4EditField.Value==6
                app.Image9.ImageSource='6.png';
            end
            if app.p2dice5EditField.Value==1
                app.Image10.ImageSource='1.png';
            elseif app.p2dice5EditField.Value==2
                app.Image10.ImageSource='2.png';
            elseif app.p2dice5EditField.Value==3
                app.Image10.ImageSource='3.png';
            elseif app.p2dice5EditField.Value==4
                app.Image10.ImageSource='4.png';
            elseif app.p2dice5EditField.Value==5
                app.Image10.ImageSource='5.png';
            elseif app.p2dice5EditField.Value==6
                app.Image10.ImageSource='6.png';
            end
            
        end

        % Button pushed function: p2plus1
        function p2plus1ButtonPushed(app, event)
            if app.p2dice5EditField.Value<6
                app.p2dice5EditField.Value=app.p2dice5EditField.Value+1;
            end
            set(app.p2plus1,'enable','off');
            set(app.p2minus1,'enable','off');
            if app.p2dice5EditField.Value==1
                app.Image10.ImageSource='1.png';
            elseif app.p2dice5EditField.Value==2
                app.Image10.ImageSource='2.png';
            elseif app.p2dice5EditField.Value==3
                app.Image10.ImageSource='3.png';
            elseif app.p2dice5EditField.Value==4
                app.Image10.ImageSource='4.png';
            elseif app.p2dice5EditField.Value==5
                app.Image10.ImageSource='5.png';
            elseif app.p2dice5EditField.Value==6
                app.Image10.ImageSource='6.png';
            end
        end

        % Button pushed function: p2minus1
        function p2minus1ButtonPushed(app, event)
            if app.p2dice5EditField.Value>1
                app.p2dice5EditField.Value=app.p2dice5EditField.Value-1;
            end
            set(app.p2minus1,'enable','off');
            set(app.p2plus1,'enable','off');
            if app.p2dice5EditField.Value==1
                app.Image10.ImageSource='1.png';
            elseif app.p2dice5EditField.Value==2
                app.Image10.ImageSource='2.png';
            elseif app.p2dice5EditField.Value==3
                app.Image10.ImageSource='3.png';
            elseif app.p2dice5EditField.Value==4
                app.Image10.ImageSource='4.png';
            elseif app.p2dice5EditField.Value==5
                app.Image10.ImageSource='5.png';
            elseif app.p2dice5EditField.Value==6
                app.Image10.ImageSource='6.png';
            end
        end

        % Button pushed function: p1finishrollingButton
        function p1finishrollingButtonPushed(app, event)
            app.p1s=1;
            if app.p2s==1
                set(app.seethescoreButton,'enable','on')
            end
            app.p1diceinarray=[app.p1dice1EditField.Value,app.p1dice2EditField.Value,app.p1dice3EditField.Value,app.p1dice4EditField.Value,app.p1dice5EditField.Value];
            b=sort(app.p1diceinarray);
            b1=b(b==1);
            b2=b(b==2);
            b3=b(b==3);
            b4=b(b==4);
            b5=b(b==5);
            b6=b(b==6);
            p1length=[length(b1),length(b2),length(b3),length(b4),length(b5),length(b6)];
            app.p1compare=sort(p1length);
            if app.p1compare(6)==5
                app.p1scoreEditField.Value=8;
            elseif app.p1compare(6)==4
                app.p1scoreEditField.Value=7;
            elseif app.p1compare(6)==3 & app.p1compare(5)==2
                app.p1scoreEditField.Value=6;
            elseif (p1length==[0,1,1,1,1,1]) | (p1length==[1,1,1,1,1,0])
                app.p1scoreEditField.Value=5;
            elseif app.p1compare(6)==3 & app.p1compare(5)==1
                app.p1scoreEditField.Value=4;
            elseif app.p1compare(6)==2 & app.p1compare(5)==2
                app.p1scoreEditField.Value=3;
            elseif app.p1compare(6)==2 & app.p1compare(5)==1
                app.p1scoreEditField.Value=2;
            else
                app.p1scoreEditField.Value=1;
            end
        end

        % Button pushed function: p2finishrollingButton
        function p2finishrollingButtonPushed(app, event)
            app.p2s=1;
            if app.p1s==1
                set(app.seethescoreButton,'enable','on')
            end
            app.p2diceinarray=[app.p2dice1EditField.Value,app.p2dice2EditField.Value,app.p2dice3EditField.Value,app.p2dice4EditField.Value,app.p2dice5EditField.Value];
            c=sort(app.p2diceinarray);
            c1=c(c==1);
            c2=c(c==2);
            c3=c(c==3);
            c4=c(c==4);
            c5=c(c==5);
            c6=c(c==6);
            p2length=[length(c1),length(c2),length(c3),length(c4),length(c5),length(c6)];
            app.p2compare=sort(p2length);
            if app.p2compare(6)==5
                app.p2scoreEditField.Value=8;
            elseif app.p2compare(6)==4
                app.p2scoreEditField.Value=7;
            elseif (app.p2compare(6)==3)&(app.p2compare(5))==2
                app.p2scoreEditField.Value=6;
            elseif (p2length==[0,1,1,1,1,1]) | (p2length==[1,1,1,1,1,0])
                app.p2scoreEditField.Value=5;
            elseif app.p2compare(6)==3 & app.p2compare(5)==1
                app.p2scoreEditField.Value=4;
            elseif app.p2compare(6)==2 & app.p2compare(5)==2
                app.p2scoreEditField.Value=3;
            elseif app.p2compare(6)==2 & app.p2compare(5)==1
                app.p2scoreEditField.Value=2;
            else
                app.p2scoreEditField.Value=1;
            end

        end

        % Value changed function: numberofgamesEditField
        function numberofgamesEditFieldValueChanged(app, event)
            value = app.numberofgamesEditField.Value;

            if mod(value,2)==1
                set(app.startgameButton,'enable','on')
            else
                set(app.startgameButton,'enable','off')
                app.instructionEditField.Value=['You must enter odd number of games'];
            end
        end

        % Button pushed function: startgameButton
        function startgameButtonPushed(app, event)
            set(app.player1rollthediceButton,'enable','on');
            set(app.player2rollthediceButton,'enable','on');
            set(app.gamerule, 'visible','off');
        end

        % Button pushed function: seethescoreButton
        function seethescoreButtonPushed(app, event)
            
            if app.times.Value<=app.numberofgamesEditField.Value
                A = app.p1scoreEditField.Value; 
                B = app.p2scoreEditField.Value; 
                app.p1sum.Value = app.p1sum.Value+A;
                app.p2sum.Value = app.p2sum.Value+B;

                app.UITable2.Data = [app.p1sum.Value,app.p2sum.Value];
                app.times.Value=app.times.Value+1;
            
            else
                if app.p1sum.Value>app.p2sum.Value
                    app.instructionEditField.Value='Congratulations! Player1 win the game!';
                    
                elseif app.p1sum.Value<app.p2sum.Value
                    app.instructionEditField.Value='Congratulations! Player2 win the game!';
                    
                else
                    app.instructionEditField.Value='Tie';
                    
                end
            end
            
            
        end

        % Button pushed function: seetherulesfirstButton
        function seetherulesfirstButtonPushed(app, event)
            set(app.gamerule,'visible','on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.player1rollthediceButton = uibutton(app.GridLayout, 'push');
            app.player1rollthediceButton.ButtonPushedFcn = createCallbackFcn(app, @player1rollthediceButtonPushed, true);
            app.player1rollthediceButton.Text = ' player1 roll the dice';
            app.player2rollthediceButton = uibutton(app.GridLayout, 'push');
            app.player2rollthediceButton.ButtonPushedFcn = createCallbackFcn(app, @player2rollthediceButtonPushed, true);
            app.player2rollthediceButton.Text = 'player2 roll the dice';
            app.p2plus1 = uibutton(app.GridLayout, 'push');
            app.p2plus1.ButtonPushedFcn = createCallbackFcn(app, @p2plus1ButtonPushed, true);
            app.p2plus1.Text = '+1';
            app.p2minus1 = uibutton(app.GridLayout, 'push');
            app.p2minus1.ButtonPushedFcn = createCallbackFcn(app, @p2minus1ButtonPushed, true);
            app.p2minus1.Text = '-1';
            app.p1plus1 = uibutton(app.GridLayout, 'push');
            app.p1plus1.ButtonPushedFcn = createCallbackFcn(app, @p1plus1ButtonPushed, true);
            app.p1plus1.Text = '+1';
            app.p1minus1 = uibutton(app.GridLayout, 'push');
            app.p1minus1.ButtonPushedFcn = createCallbackFcn(app, @p1minus1ButtonPushed, true);
            app.p1minus1.Text = '-1';
            app.p1dice1EditFieldLabel = uilabel(app.GridLayout);
            app.p1dice1EditFieldLabel.Text = 'p1dice1';
            app.p1dice1EditField = uieditfield(app.GridLayout, 'numeric');
            app.p1dice2EditFieldLabel = uilabel(app.GridLayout);
            app.p1dice2EditFieldLabel.Text = 'p1dice2';
            app.p1dice2EditField = uieditfield(app.GridLayout, 'numeric');
            app.p1dice3EditFieldLabel = uilabel(app.GridLayout);
            app.p1dice3EditFieldLabel.Text = 'p1dice3';
            app.p1dice3EditField = uieditfield(app.GridLayout, 'numeric');
            app.p1dice4EditFieldLabel = uilabel(app.GridLayout);
            app.p1dice4EditFieldLabel.Text = 'p1dice4';
            app.p1dice4EditField = uieditfield(app.GridLayout, 'numeric');
            app.p1dice5EditFieldLabel = uilabel(app.GridLayout);
            app.p1dice5EditFieldLabel.Text = 'p1dice5';
            app.p1dice5EditField = uieditfield(app.GridLayout, 'numeric');
            app.p2dice1EditFieldLabel = uilabel(app.GridLayout);
            app.p2dice1EditFieldLabel.Text = 'p2dice1';
            app.p2dice1EditField = uieditfield(app.GridLayout, 'numeric');
            app.p2dice2EditFieldLabel = uilabel(app.GridLayout);
            app.p2dice2EditFieldLabel.Text = 'p2dice2';
            app.p2dice2EditField = uieditfield(app.GridLayout, 'numeric');
            app.p2dice3EditFieldLabel = uilabel(app.GridLayout);
            app.p2dice3EditFieldLabel.Text = 'p2dice3';
            app.p2dice3EditField = uieditfield(app.GridLayout, 'numeric');
            app.p2dice4EditFieldLabel = uilabel(app.GridLayout);
            app.p2dice4EditFieldLabel.Text = 'p2dice4';
            app.p2dice4EditField = uieditfield(app.GridLayout, 'numeric');
            app.p2dice5EditFieldLabel = uilabel(app.GridLayout);
            app.p2dice5EditFieldLabel.Text = 'p2dice5';
            app.p2dice5EditField = uieditfield(app.GridLayout, 'numeric');
            app.p1finishrollingButton = uibutton(app.GridLayout, 'push');
            app.p1finishrollingButton.ButtonPushedFcn = createCallbackFcn(app, @p1finishrollingButtonPushed, true);
            app.p1finishrollingButton.Text = 'p1 finish rolling';
            app.p2finishrollingButton = uibutton(app.GridLayout, 'push');
            app.p2finishrollingButton.ButtonPushedFcn = createCallbackFcn(app, @p2finishrollingButtonPushed, true);
            app.p2finishrollingButton.Text = 'p2 finish rolling';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Five of a Kind'; 'Four of a Kind'; 'Full House'; 'Straight'; 'Three of a Kind'; 'Two  Pairs'; 'Pair'; 'High Dice'};
            app.UITable.RowName = {};
            app.numberofgamesEditFieldLabel = uilabel(app.GridLayout);
            app.numberofgamesEditFieldLabel.Text = 'number of games';
            app.numberofgamesEditField = uieditfield(app.GridLayout, 'numeric');
            app.numberofgamesEditField.ValueChangedFcn = createCallbackFcn(app, @numberofgamesEditFieldValueChanged, true);
            app.startgameButton = uibutton(app.GridLayout, 'push');
            app.startgameButton.ButtonPushedFcn = createCallbackFcn(app, @startgameButtonPushed, true);
            app.startgameButton.Text = 'start game';
            app.p1scoreEditFieldLabel = uilabel(app.GridLayout);
            app.p1scoreEditFieldLabel.Text = 'p1score';
            app.p1scoreEditField = uieditfield(app.GridLayout, 'numeric');
            app.p2scoreEditFieldLabel = uilabel(app.GridLayout);
            app.p2scoreEditFieldLabel.Text = 'p2score';
            app.p2scoreEditField = uieditfield(app.GridLayout, 'numeric');
            app.instructionEditFieldLabel = uilabel(app.GridLayout);
            app.instructionEditFieldLabel.Text = 'instruction';
            app.instructionEditField = uieditfield(app.GridLayout, 'text');
            app.UITable2 = uitable(app.GridLayout);
            app.UITable2.ColumnName = {'p1sum'; 'p2sum'};
            app.UITable2.RowName = {};
            app.seethescoreButton = uibutton(app.GridLayout, 'push');
            app.seethescoreButton.ButtonPushedFcn = createCallbackFcn(app, @seethescoreButtonPushed, true);
            app.seethescoreButton.Text = 'see the score';
            app.Image = uiimage(app.GridLayout);
            app.Image2 = uiimage(app.GridLayout);
            app.Image3 = uiimage(app.GridLayout);
            app.Image4 = uiimage(app.GridLayout);
            app.Image5 = uiimage(app.GridLayout);
            app.Image6 = uiimage(app.GridLayout);
            app.Image7 = uiimage(app.GridLayout);
            app.Image8 = uiimage(app.GridLayout);
            app.Image9 = uiimage(app.GridLayout);
            app.Image10 = uiimage(app.GridLayout);
            app.seetherulesfirstButton = uibutton(app.GridLayout, 'push');
            app.seetherulesfirstButton.ButtonPushedFcn = createCallbackFcn(app, @seetherulesfirstButtonPushed, true);
            app.seetherulesfirstButton.Text = 'see the rules first!!!';
            app.gamerule = uitextarea(app.GridLayout);
            app.gamerule.Value = {'1. This game consists of two players. Before the game starts, enter an odd number as the number of rounds to play.  '; '2. Start the game!  Each player rolls five dices. “Dice 5” is the unique dice of each player. Players can choose to +1 or -1 on the value of “Dice 5” to gain more points. After finishing rolling, each player need to press finish rolling button to see the score.'; '3.Scoring! According to the type of die, the system will automatically give the score.'; '4. End game! After the turns that sated at the beginning of game, the player who gain more score win.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dice

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end